classdef sg_pattern_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PatternSpinner          matlab.ui.control.Spinner
        PatternSpinnerLabel     matlab.ui.control.Label
        LoadimagesButton        matlab.ui.control.Button
        ClearbkgimButton        matlab.ui.control.Button
        BkgimageonButton        matlab.ui.control.StateButton
        rightclickremoveButton  matlab.ui.control.StateButton
        leftclickaddButton      matlab.ui.control.StateButton
        ZdepthSpinner           matlab.ui.control.Spinner
        ZdepthSpinnerLabel      matlab.ui.control.Label
        UpdateplotButton        matlab.ui.control.Button
        imagedirEditField       matlab.ui.control.EditField
        imagedirEditFieldLabel  matlab.ui.control.Label
        AdddirButton            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        app_main; % Description
        data;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_main)
            app.app_main = app_main;
            f_sg_pp_initialize(app);
        end

        % Button pushed function: AdddirButton
        function AdddirButtonPushed(app, event)
            f_sg_pp_add_dir(app);
        end

        % Button pushed function: LoadimagesButton
        function LoadimagesButtonPushed(app, event)
            f_sg_pp_load_images(app);
        end

        % Callback function
        function patternDropDownValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
        end

        % Button pushed function: UpdateplotButton
        function UpdateplotButtonPushed(app, event)
            f_sg_pp_update_pat_plot(app);
        end

        % Value changed function: ZdepthSpinner
        function ZdepthSpinnerValueChanged(app, event)
            f_sg_pp_update_bkg_im(app);
            f_sg_pp_update_pat_plot(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            f_sg_pp_button_down(app, event);
        end

        % Value changed function: BkgimageonButton
        function BkgimageonButtonValueChanged(app, event)
            f_sg_pp_update_bkg_im(app);
        end

        % Button pushed function: ClearbkgimButton
        function ClearbkgimButtonPushed(app, event)
            f_sg_pp_clear_bkg_im(app);
        end

        % Value changed function: PatternSpinner
        function PatternSpinnerValueChanged(app, event)
            f_sg_pp_update_pat_plot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.AdddirButton = uibutton(app.UIFigure, 'push');
            app.AdddirButton.ButtonPushedFcn = createCallbackFcn(app, @AdddirButtonPushed, true);
            app.AdddirButton.Tooltip = {'Add all background images to a folder with a tag in file name (''_z##) corresponding to z depths and load them'};
            app.AdddirButton.Text = 'Add dir';
            app.imagedirEditFieldLabel = uilabel(app.UIFigure);
            app.imagedirEditFieldLabel.Text = 'image dir';
            app.imagedirEditField = uieditfield(app.UIFigure, 'text');
            app.UpdateplotButton = uibutton(app.UIFigure, 'push');
            app.UpdateplotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateplotButtonPushed, true);
            app.UpdateplotButton.Text = 'Update plot';
            app.ZdepthSpinnerLabel = uilabel(app.UIFigure);
            app.ZdepthSpinnerLabel.Text = 'Z depth';
            app.ZdepthSpinner = uispinner(app.UIFigure);
            app.ZdepthSpinner.Step = 10;
            app.ZdepthSpinner.ValueChangedFcn = createCallbackFcn(app, @ZdepthSpinnerValueChanged, true);
            app.ZdepthSpinner.Tooltip = {''};
            app.leftclickaddButton = uibutton(app.UIFigure, 'state');
            app.leftclickaddButton.Text = 'left click add';
            app.leftclickaddButton.Value = true;
            app.rightclickremoveButton = uibutton(app.UIFigure, 'state');
            app.rightclickremoveButton.Text = 'right click remove';
            app.rightclickremoveButton.Value = true;
            app.BkgimageonButton = uibutton(app.UIFigure, 'state');
            app.BkgimageonButton.ValueChangedFcn = createCallbackFcn(app, @BkgimageonButtonValueChanged, true);
            app.BkgimageonButton.Text = 'Bkg image on';
            app.BkgimageonButton.Value = true;
            app.ClearbkgimButton = uibutton(app.UIFigure, 'push');
            app.ClearbkgimButton.ButtonPushedFcn = createCallbackFcn(app, @ClearbkgimButtonPushed, true);
            app.ClearbkgimButton.Text = 'Clear bkg im';
            app.LoadimagesButton = uibutton(app.UIFigure, 'push');
            app.LoadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimagesButtonPushed, true);
            app.LoadimagesButton.Tooltip = {'Add all background images to a folder with a tag in file name (''_z##) corresponding to z depths and load them'};
            app.LoadimagesButton.Text = 'Load images';
            app.PatternSpinnerLabel = uilabel(app.UIFigure);
            app.PatternSpinnerLabel.Text = 'Pattern';
            app.PatternSpinner = uispinner(app.UIFigure);
            app.PatternSpinner.Limits = [1 Inf];
            app.PatternSpinner.RoundFractionalValues = 'on';
            app.PatternSpinner.ValueChangedFcn = createCallbackFcn(app, @PatternSpinnerValueChanged, true);
            app.PatternSpinner.Tooltip = {'Add all background images to a folder with a tag in file name (''_z##) corresponding to z depths'};
            app.PatternSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sg_pattern_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end