classdef SLM_control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParametersTab                   matlab.ui.container.Tab
        SLMwidthEditFieldLabel          matlab.ui.control.Label
        SLMwidthEditField               matlab.ui.control.NumericEditField
        SLMheightEditFieldLabel         matlab.ui.control.Label
        SLMheightEditField              matlab.ui.control.NumericEditField
        ObjectiveNAEditFieldLabel       matlab.ui.control.Label
        ObjectiveNAEditField            matlab.ui.control.NumericEditField
        ObjectiveRIEditFieldLabel       matlab.ui.control.Label
        ObjectiveRIEditField            matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        SLMpresetoffsetXLabel           matlab.ui.control.Label
        SLMpresetoffsetXEditField       matlab.ui.control.NumericEditField
        SLMpresetoffsetYEditFieldLabel  matlab.ui.control.Label
        SLMpresetoffsetYEditField       matlab.ui.control.NumericEditField
        ObjectiveMagXEditFieldLabel     matlab.ui.control.Label
        ObjectiveMagXEditField          matlab.ui.control.NumericEditField
        BeamdiameterpixEditFieldLabel   matlab.ui.control.Label
        BeamdiameterpixEditField        matlab.ui.control.NumericEditField
        NIDAQdeviceEditFieldLabel       matlab.ui.control.Label
        NIDAQdeviceEditField            matlab.ui.control.EditField
        DAQcounterchannelEditFieldLabel  matlab.ui.control.Label
        DAQcounterchannelEditField      matlab.ui.control.NumericEditField
        PlotSLMupdateratesCheckBox      matlab.ui.control.CheckBox
        InsertrefimageinscansCheckBox   matlab.ui.control.CheckBox
        SaveopsButton                   matlab.ui.control.Button
        SLMROIeditorPanel               matlab.ui.container.Panel
        SelectROIDropDownLabel          matlab.ui.control.Label
        SelectROIDropDown               matlab.ui.control.DropDown
        ROInameEditFieldLabel           matlab.ui.control.Label
        ROInameEditField                matlab.ui.control.EditField
        AddROIButton                    matlab.ui.control.Button
        DeleteROIButton                 matlab.ui.control.Button
        WavelengthnmEditField_2Label    matlab.ui.control.Label
        WavelengthnmEditField_2         matlab.ui.control.NumericEditField
        PixelfractionheightpixpixEditFieldLabel  matlab.ui.control.Label
        PixelfractionheightpixpixEditField  matlab.ui.control.EditField
        PixelfractionwidthpixpixEditFieldLabel  matlab.ui.control.Label
        PixelfractionwidthpixpixEditField  matlab.ui.control.EditField
        LUTfilereactivateSLMDropDownLabel  matlab.ui.control.Label
        LUTfilereactivateSLMDropDown    matlab.ui.control.DropDown
        SaveROIButton                   matlab.ui.control.Button
        XYZpatternTab                   matlab.ui.container.Tab
        ListofpointsLabel               matlab.ui.control.Label
        CreateimagingphasePanel         matlab.ui.container.Panel
        UploadHologramButton            matlab.ui.control.Button
        ZOffsetumEditFieldLabel         matlab.ui.control.Label
        ZOffsetumEditField              matlab.ui.control.NumericEditField
        ManualNAcorrectionCheckBox      matlab.ui.control.CheckBox
        ManualNAEditFieldLabel          matlab.ui.control.Label
        ManualNAEditField               matlab.ui.control.NumericEditField
        DefocusLabel                    matlab.ui.control.Label
        XYdisplacementLabel             matlab.ui.control.Label
        XdisplacementEditFieldLabel     matlab.ui.control.Label
        XdisplacementEditField          matlab.ui.control.NumericEditField
        YdisplacementEditFieldLabel     matlab.ui.control.Label
        YdisplacementEditField          matlab.ui.control.NumericEditField
        ViewHologramButtonMultiplane    matlab.ui.control.Button
        WeightEditFieldLabel            matlab.ui.control.Label
        WeightEditField                 matlab.ui.control.NumericEditField
        PlanenumberEditFieldLabel       matlab.ui.control.Label
        PlanenumberEditField            matlab.ui.control.NumericEditField
        UploadselectedButton            matlab.ui.control.Button
        UIImagePhaseTable               matlab.ui.control.Table
        RemoveselectedButton            matlab.ui.control.Button
        AddtohologramButton             matlab.ui.control.Button
        ViewpredictedPSFButton          matlab.ui.control.Button
        ViewselectedphaseButton         matlab.ui.control.Button
        PlaneSpinnerLabel               matlab.ui.control.Label
        PlaneSpinner                    matlab.ui.control.Spinner
        ViewplanephaseButton            matlab.ui.control.Button
        UploadPlaneButton               matlab.ui.control.Button
        ViewselectedfftButton           matlab.ui.control.Button
        ViewplanefftButton              matlab.ui.control.Button
        fftdefocusumEditFieldLabel      matlab.ui.control.Label
        fftdefocusumEditField           matlab.ui.control.NumericEditField
        fftampsquaredCheckBox           matlab.ui.control.CheckBox
        fftzoomed20xCheckBox            matlab.ui.control.CheckBox
        PatternlistDropDownLabel        matlab.ui.control.Label
        PatternlistDropDown             matlab.ui.control.DropDown
        PatternROIDropDownLabel         matlab.ui.control.Label
        PatternROIDropDown              matlab.ui.control.DropDown
        PatternnameEditFieldLabel       matlab.ui.control.Label
        PatternnameEditField            matlab.ui.control.EditField
        SavepatternButton               matlab.ui.control.Button
        AddpatternButton                matlab.ui.control.Button
        DeletepatternButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ScanTab                         matlab.ui.container.Tab
        FrameswitchingmethodButtonGroup  matlab.ui.container.ButtonGroup
        EndofframetriggeringButton      matlab.ui.control.ToggleButton
        TimedelayButton                 matlab.ui.control.ToggleButton
        ImagePanel                      matlab.ui.container.Panel
        InitializeimagingButton         matlab.ui.control.StateButton
        ImagingReadyLamp                matlab.ui.control.Lamp
        NumVolumesEditFieldLabel        matlab.ui.control.Label
        NumVolumesEditField             matlab.ui.control.NumericEditField
        CalibrationTab                  matlab.ui.container.Tab
        LUTcalibrationdataPanel         matlab.ui.container.Panel
        BitDepthEditFieldLabel          matlab.ui.control.Label
        BitDepthEditField               matlab.ui.control.NumericEditField
        NumRegionsEditFieldLabel        matlab.ui.control.Label
        NumRegionsEditField             matlab.ui.control.NumericEditField
        PixelsPerStripeEditFieldLabel   matlab.ui.control.Label
        PixelsPerStripeEditField        matlab.ui.control.NumericEditField
        AnalysissavefilenameEditFieldLabel  matlab.ui.control.Label
        AnalysissavefilenameEditField   matlab.ui.control.EditField
        RunLUTcalibrationButton         matlab.ui.control.StateButton
        AnalyzeLUTdataButton            matlab.ui.control.Button
        RunLUTcalibReadyLamp            matlab.ui.control.Lamp
        BaselinepixelvalueEditFieldLabel  matlab.ui.control.Label
        BaselinepixelvalueEditField     matlab.ui.control.NumericEditField
        TotalframesEditFieldLabel       matlab.ui.control.Label
        TotalframesEditField            matlab.ui.control.NumericEditField
        AxialcalibrationZPanel          matlab.ui.container.Panel
        AxialcalibrationfileEditFieldLabel  matlab.ui.control.Label
        AxialcalibrationfileEditField   matlab.ui.control.EditField
        PlotaxialcalibrationdataButton  matlab.ui.control.Button
        LateralcalibrationxyaffinetransformPanel  matlab.ui.container.Panel
        AffinetransformatiomatrixfileEditFieldLabel  matlab.ui.control.Label
        AffinetransformatiomatrixfileEditField  matlab.ui.control.EditField
        LateralpixelumscalingfileEditFieldLabel  matlab.ui.control.Label
        LateralpixelumscalingfileEditField  matlab.ui.control.EditField
        GenerateHologramTab             matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        BlankTab                        matlab.ui.container.Tab
        GenerateBlankButton             matlab.ui.control.Button
        PixelValueLabel                 matlab.ui.control.Label
        BlankPixelValueEditField        matlab.ui.control.NumericEditField
        FresnelLensTab                  matlab.ui.container.Tab
        GenerateFresnelLensButton       matlab.ui.control.Button
        CenterXEditFieldLabel           matlab.ui.control.Label
        FresCenterXEditField            matlab.ui.control.NumericEditField
        CenterYEditFieldLabel           matlab.ui.control.Label
        FresCenterYEditField            matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        FresRadiusEditField             matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        FresPowerEditField              matlab.ui.control.NumericEditField
        FresCylindricalCheckBox         matlab.ui.control.CheckBox
        FresHorizontalCheckBox          matlab.ui.control.CheckBox
        ZernikeTab                      matlab.ui.container.Tab
        GenerateZernikeButton           matlab.ui.control.Button
        CenterXEditField_2Label         matlab.ui.control.Label
        CenterXEditField                matlab.ui.control.NumericEditField
        CenterYEditField_5Label         matlab.ui.control.Label
        CenterYEditField                matlab.ui.control.NumericEditField
        RadiusEditFieldLabel_2          matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        PistonEditFieldLabel            matlab.ui.control.Label
        PistonEditField                 matlab.ui.control.NumericEditField
        TiltXEditFieldLabel             matlab.ui.control.Label
        TiltXEditField                  matlab.ui.control.NumericEditField
        TiltYEditFieldLabel             matlab.ui.control.Label
        TiltYEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel_2           matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        AstigXEditFieldLabel            matlab.ui.control.Label
        AstigXEditField                 matlab.ui.control.NumericEditField
        AstigYEditFieldLabel            matlab.ui.control.Label
        AstigYEditField                 matlab.ui.control.NumericEditField
        ComaXEditFieldLabel             matlab.ui.control.Label
        ComaXEditField                  matlab.ui.control.NumericEditField
        ComaYEditFieldLabel             matlab.ui.control.Label
        ComaYEditField                  matlab.ui.control.NumericEditField
        PrimarySphericalEditFieldLabel  matlab.ui.control.Label
        PrimarySphericalEditField       matlab.ui.control.NumericEditField
        TrefoilXEditFieldLabel          matlab.ui.control.Label
        TrefoilXEditField               matlab.ui.control.NumericEditField
        TrefoilYEditFieldLabel          matlab.ui.control.Label
        TrefoilYEditField               matlab.ui.control.NumericEditField
        SecondaryAstigXEditFieldLabel   matlab.ui.control.Label
        SecondaryAstigXEditField        matlab.ui.control.NumericEditField
        SecondaryAstigYEditFieldLabel   matlab.ui.control.Label
        SecondaryAstigYEditField        matlab.ui.control.NumericEditField
        SecondaryComaXEditFieldLabel    matlab.ui.control.Label
        SecondaryComaXEditField         matlab.ui.control.NumericEditField
        SecondaryComaYEditFieldLabel    matlab.ui.control.Label
        SecondaryComaYEditField         matlab.ui.control.NumericEditField
        SecondarySphericalEditFieldLabel  matlab.ui.control.Label
        SecondarySphericalEditField     matlab.ui.control.NumericEditField
        TetrafoilXEditFieldLabel        matlab.ui.control.Label
        TetrafoilXEditField             matlab.ui.control.NumericEditField
        TetrafoilYEditFieldLabel        matlab.ui.control.Label
        TetrafoilYEditField             matlab.ui.control.NumericEditField
        TertiarySphericalEditFieldLabel  matlab.ui.control.Label
        TertiarySphericalEditField      matlab.ui.control.NumericEditField
        QuaternarySphericalEditFieldLabel  matlab.ui.control.Label
        QuaternarySphericalEditField    matlab.ui.control.NumericEditField
        BlazedGratingTab                matlab.ui.container.Tab
        GenerateBlazedGratingButton     matlab.ui.control.Button
        PeriodEditFieldLabel            matlab.ui.control.Label
        BlazPeriodEditField             matlab.ui.control.NumericEditField
        BlazIncreasingCheckBox          matlab.ui.control.CheckBox
        BlazHorizontalCheckBox          matlab.ui.control.CheckBox
        StripesTab                      matlab.ui.container.Tab
        PixelsPerStripeLabel            matlab.ui.control.Label
        StripePixelPerStripeEditField   matlab.ui.control.NumericEditField
        GenerateStripesButton           matlab.ui.control.Button
        PixelValueEditFieldLabel        matlab.ui.control.Label
        StripePixelValueEditField       matlab.ui.control.NumericEditField
        GrayEditFieldLabel              matlab.ui.control.Label
        StripeGrayEditField             matlab.ui.control.NumericEditField
        DefocusTab                      matlab.ui.container.Tab
        UploadHologramButtonGenerate    matlab.ui.control.Button
        SelectROIDropDown_2Label        matlab.ui.control.Label
        SelectROIDropDown_2             matlab.ui.control.DropDown
        UIAxesGenerateHologram          matlab.ui.control.UIAxes
        AdaptiveOpticsmodalTab          matlab.ui.container.Tab
        ZernikeListTable                matlab.ui.control.Table
        ZernikeModestoscanadjustweightsifneededLabel  matlab.ui.control.Label
        TestHologramandweightsPanel     matlab.ui.container.Panel
        PlotHologramButton              matlab.ui.control.Button
        UploadHologramButton_2          matlab.ui.control.Button
        ModeindexEditFieldLabel         matlab.ui.control.Label
        ModeindexEditField              matlab.ui.control.NumericEditField
        weightEditFieldLabel            matlab.ui.control.Label
        weightEditField                 matlab.ui.control.NumericEditField
        AOscanZernikepolynomialsPanel   matlab.ui.container.Panel
        ZernikeReadyLamp                matlab.ui.control.Lamp
        ScanZernikeButton               matlab.ui.control.StateButton
        ShufflemodesCheckBox            matlab.ui.control.CheckBox
        SavefiletagEditFieldLabel       matlab.ui.control.Label
        SavefiletagEditField            matlab.ui.control.EditField
        ScanspermodeEditFieldLabel      matlab.ui.control.Label
        ScanspermodeEditField           matlab.ui.control.NumericEditField
        ScansperVolZEditFieldLabel      matlab.ui.control.Label
        ScansperVolZEditField           matlab.ui.control.NumericEditField
        AnalyzeoutputimagesPanel        matlab.ui.container.Panel
        LoaddataButton                  matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        TotalVolumesmodesEditFieldLabel  matlab.ui.control.Label
        TotalVolumesmodesEditField      matlab.ui.control.NumericEditField
        FillzerniketablePanel           matlab.ui.container.Panel
        FillmodestableButton            matlab.ui.control.Button
        MaxmodesEditFieldLabel          matlab.ui.control.Label
        MaxmodesEditField               matlab.ui.control.NumericEditField
        WeightlimitEditFieldLabel       matlab.ui.control.Label
        WeightlimitEditField            matlab.ui.control.NumericEditField
        WeightstepEditFieldLabel        matlab.ui.control.Label
        WeightstepEditField             matlab.ui.control.NumericEditField
        ComputeAOcorrectionButton       matlab.ui.control.Button
        ViewAOphaseButton               matlab.ui.control.Button
        AOcorrectionfilesDropDownLabel  matlab.ui.control.Label
        AOcorrectionfilesDropDown       matlab.ui.control.DropDown
        TotalframesEditField_2Label     matlab.ui.control.Label
        TotalframesEditField_AO         matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        ActivateSLMLamp                 matlab.ui.control.Lamp
        ActivateSLMButton               matlab.ui.control.StateButton
        InitializeDAQButton             matlab.ui.control.Button
        InitializeDAQLamp               matlab.ui.control.Lamp
        BlankSLMButton                  matlab.ui.control.Button
        ApplyAOcorrectionButton         matlab.ui.control.StateButton
        InitializeAOLamp                matlab.ui.control.Lamp
        refimSLMButton                  matlab.ui.control.Button
        XoffsetButton                   matlab.ui.control.Button
        AOcorrectionavailableLampLabel  matlab.ui.control.Label
        AOcorrectionavailableLamp       matlab.ui.control.Lamp
        UITablecurrentcoord             matlab.ui.control.Table
        CurrentcoordLabel               matlab.ui.control.Label
        ApplyXYZcalibrationButton       matlab.ui.control.StateButton
        XYZcalibrationLamp              matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        SLM_ops; % Description
        SLM_roi_list;
        xyz_patterns;
        SLM_Image;
        GUI_ops;
        current_SLM_coord = [];
        SLM_blank_im
        SLM_ref_im
        ref_offset = 100;
        SLM_X_offset_im
        X_offset = 30;
        SLM_AO_Image;
        SLM_Image_pointer;
        SLM_blank_pointer;
        SLM_X_offset_im_pointer;
        SLM_ref_im_pointer;
        SLM_Image_plot;
        ViewHologramImage
        ViewHologramImage_pointer;
        hologram_fig_plot;
        UIImagePhaseTableSelection;
        DAQ_session;
        AO_ignore_modes_list = [1, 2, 3];
        AO_last_zernike_data = cell(2,1);
        AO_last_LUT_data = cell(2,1);
        AO_correction_ready = false;
        AO_correction_data = [];
        xyz_affine_tf_mat = [1 0 0; 0 1 0; 0 0 1];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            pwd2 = fileparts(which('SLM_control_GUI.mlapp'));
            app.SLM_ops.GUI_dir = pwd2;
            
            if exist([pwd2 '\SLM_GUI_funcions'], 'dir')
                addpath([pwd2 '\SLM_GUI_funcions']);
            else
                error('RAFA: You need to move to SLM_microscope_GUI directory and reopen GUI!!!');
            end  
            
            app.SLM_ops = f_SLM_default_ops(app.SLM_ops);
            f_SLM_process_ops(app);
            
            app.SLM_ops = f_SLM_BNS_initialize(app.SLM_ops);
            app.ActivateSLMButton.Value = 1;
            app.ActivateSLMLamp.Color = [0.00,1.00,0.00];

            f_SLM_initialize_GUI_params(app);    
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.ActivateSLMButton.Value
                f_SLM_BNS_update(app.SLM_ops, app.SLM_blank_pointer);
                app.SLM_ops = f_SLM_BNS_close(app.SLM_ops);
            end
            delete(app);
        end

        % Value changed function: ActivateSLMButton
        function ActivateSLMButtonValueChanged(app, event)
            if app.ActivateSLMButton.Value
                app.SLM_ops = f_SLM_BNS_initialize(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.00,1.00,0.00];
            else
                app.SLM_ops = f_SLM_BNS_close(app.SLM_ops);
                app.ActivateSLMLamp.Color = [0.80,0.80,0.80];
            end
        end

        % Button pushed function: InitializeDAQButton
        function InitializeDAQButtonPushed(app, event)
            f_SLM_initialize_DAQ(app);
        end

        % Value changed function: ApplyXYZcalibrationButton
        function ApplyXYZcalibrationButtonValueChanged(app, event)
            if app.ApplyXYZcalibrationButton.Value    
                f_SLM_apply_xyz_calibration(app, 1);
            else
                f_SLM_apply_xyz_calibration(app, 0);
            end
        end

        % Value changed function: ApplyAOcorrectionButton
        function ApplyAOcorrectionButtonValueChanged(app, event)
            if app.ApplyAOcorrectionButton.Value
                if app.AO_correction_ready
                    f_SLM_upload_image_to_SLM(app);
                    app.InitializeAOLamp.Color = [0.00,1.00,0.00];
                else
                    app.ApplyAOcorrectionButton.Value = 0;
                    app.InitializeAOLamp.Color = [0.80,0.80,0.80];
                    disp('No adaptive optics correction data available');
                end
            else
                f_SLM_upload_image_to_SLM(app);
                app.InitializeAOLamp.Color = [0.80,0.80,0.80];
            end
        end

        % Button pushed function: BlankSLMButton
        function BlankSLMButtonPushed(app, event)
            app.SLM_Image = app.SLM_blank_im;
            app.current_SLM_coord = f_SLM_mpl_get_coords(app, 'zero');
            app.UITablecurrentcoord.Data = app.current_SLM_coord.xyzp;
            f_SLM_upload_image_to_SLM(app);
            disp('SLM blank uploaded');
        end

        % Button pushed function: XoffsetButton
        function XoffsetButtonPushed(app, event)
            app.SLM_Image = app.SLM_X_offset_im;
            app.current_SLM_coord = f_SLM_mpl_get_coords(app, 'zero');
            app.current_SLM_coord.xyzp = [app.SLM_ops.X_offset, 0, 0];
            app.UITablecurrentcoord.Data = app.current_SLM_coord.xyzp;
            f_SLM_upload_image_to_SLM(app);    
            disp('SLM X offset uploaded');
        end

        % Button pushed function: refimSLMButton
        function refimSLMButtonPushed(app, event)
            app.SLM_Image = app.SLM_ref_im;
            app.current_SLM_coord = f_SLM_mpl_get_coords(app, 'zero');
            app.current_SLM_coordxyzp = [app.SLM_ops.ref_offset, 0, 0;...
                                           -app.SLM_ops.ref_offset, 0, 0;...
                                            0, app.SLM_ops.ref_offset, 0;...
                                            0,-app.SLM_ops.ref_offset, 0];
            app.UITablecurrentcoord.Data = app.current_SLM_coord.xyzp;
            f_SLM_upload_image_to_SLM(app);    
            disp('SLM ref image uploaded');
        end

        % Button pushed function: GenerateBlankButton
        function GenerateBlankLensButtonPushed(app, event)
            f_SLM_gh_solid(app);
        end

        % Button pushed function: GenerateFresnelLensButton
        function GenerateFresnelLensButtonPushed(app, event)
            f_SLM_gh_fresnel(app);
        end

        % Button pushed function: GenerateBlazedGratingButton
        function GenerateBlazedGratingButtonPushed(app, event)
            f_SLM_gh_blazed(app)
        end

        % Button pushed function: GenerateStripesButton
        function GenerateStripesButtonPushed(app, event)
            f_SLM_gh_stripes(app)
        end

        % Button pushed function: GenerateZernikeButton
        function GenerateZernikeButtonPushed(app, event)
            f_SLM_generate_zernike(app);            
        end

        % Button pushed function: UploadHologramButtonGenerate
        function UploadHologramButtonGeneratePushedGenerate(app, event)
            f_SLM_upload_image_to_SLM(app)    
        end

        % Button pushed function: ViewHologramButtonMultiplane
        function ViewHologramButtonPushedMultiplane(app, event)
            coord = f_SLM_mpl_get_coords(app, 'manual');
            holo_image =  f_SLM_gen_holo_multiplane_image(app, coord);
            holo_image = f_SLM_AO_add_correction(app,holo_image);
            f_SLM_view_hologram_phase(app, holo_image);
            title(sprintf('Defocus %.1f um', app.ZOffsetumEditField.Value));
        end

        % Button pushed function: UploadHologramButton
        function UploadHologramButtonPushedMultiplane(app, event)
            app.current_SLM_coord = f_SLM_mpl_get_coords(app, 'manual');
            app.UITablecurrentcoord.Data = app.current_SLM_coord.xyzp;
            app.SLM_Image =  f_SLM_gen_holo_multiplane_image(app, app.current_SLM_coord);                    
            f_SLM_upload_image_to_SLM(app);
        end

        % Cell selection callback: UIImagePhaseTable
        function UIImagePhaseTableCellSelection(app, event)
            app.UIImagePhaseTableSelection = event.Indices;
        end

        % Button pushed function: AddtohologramButton
        function AddtohologramButtonPushed(app, event)
            app.UIImagePhaseTable.Data = array2table([app.UIImagePhaseTable.Data.Variables;...
                                            app.PlanenumberEditField.Value,...
                                            app.ZOffsetumEditField.Value,...
                                            app.XdisplacementEditField.Value,...
                                            app.YdisplacementEditField.Value,...
                                            app.ManualNAEditField.Value,...
                                            app.WeightEditField.Value]);
        end

        % Button pushed function: ViewselectedphaseButton
        function ViewselectedphaseButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                coord = f_SLM_mpl_get_coords(app, 'table_selection');
                holo_image = f_SLM_gen_holo_multiplane_image(app, coord);
                holo_image = f_SLM_AO_add_correction(app,holo_image);
                f_SLM_view_hologram_phase(app, holo_image);
                title(sprintf('Defocus %.1f um', app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),2).Variables));
            end
        end

        % Button pushed function: ViewselectedfftButton
        function ViewselectedfftButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                coord = f_SLM_mpl_get_coords(app, 'table_selection');
                holo_image = f_SLM_gen_holo_multiplane_image(app,coord);
                f_SLM_view_hologram_fft(app, holo_image, app.fftdefocusumEditField.Value*10e-6);
                title(sprintf('PSF at %.1f um', app.fftdefocusumEditField.Value));
            end
        end

        % Button pushed function: UploadselectedButton
        function UploadselectedButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                app.current_SLM_coord = f_SLM_mpl_get_coords(app, 'table_selection');
                app.UITablecurrentcoord.Data = app.current_SLM_coord.xyzp;
                app.SLM_Image = f_SLM_gen_holo_multiplane_image(app, app.current_SLM_coord);
                f_SLM_upload_image_to_SLM(app);
            end
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            if size(app.UIImagePhaseTableSelection,1) > 0
                app.UIImagePhaseTable.Data(app.UIImagePhaseTableSelection(1),:) = [];
                if size(app.UIImagePhaseTable.Data,1) < app.UIImagePhaseTableSelection(1)
                    app.UIImagePhaseTableSelection = [];
                end
            end
        end

        % Button pushed function: ViewplanephaseButton
        function ViewplanephaseButtonPushed(app, event)
            coord = f_SLM_mpl_get_coords(app, 'plane', round(app.PlaneSpinner.Value));
            holo_image = f_SLM_gen_holo_multiplane_image(app, coord);                                
            holo_image = f_SLM_AO_add_correction(app,holo_image);
            f_SLM_view_hologram_phase(app, holo_image);
        end

        % Button pushed function: ViewplanefftButton
        function ViewplanefftButtonPushed(app, event)
            coord = f_SLM_mpl_get_coords(app, 'plane', round(app.PlaneSpinner.Value));                            
            holo_image = f_SLM_gen_holo_multiplane_image(app, coord);                                
            f_SLM_view_hologram_fft(app, holo_image, app.fftdefocusumEditField.Value*10e-6);
            title(sprintf('PSF at %.1f um', app.fftdefocusumEditField.Value));
        end

        % Button pushed function: UploadPlaneButton
        function UploadPlaneButtonPushed(app, event)
            app.current_SLM_coord = f_SLM_mpl_get_coords(app, 'plane', round(app.PlaneSpinner.Value));
            app.UITablecurrentcoord.Data = app.current_SLM_coord.xyzp;
            app.SLM_Image = f_SLM_gen_holo_multiplane_image(app, app.current_SLM_coord);                                
            f_SLM_upload_image_to_SLM(app);
        end

        % Value changed function: InitializeimagingButton
        function InitializeimagingButtonValueChanged(app, event)
            f_SLM_initialize_imaging(app);
        end

        % Callback function
        function ScanZernikeButtonPushed(app, event)
            f_SLM_AO_scan_zernike(app);
        end

        % Button pushed function: FillmodestableButton
        function FillmodestableButtonPushed(app, event)
            f_SLM_AO_fill_modes_table(app);
        end

        % Value changed function: ScanspermodeEditField
        function ScanspermodeEditFieldValueChanged(app, event)
            f_SLM_AO_update_total_modes(app);
        end

        % Value changed function: ScansperVolZEditField
        function ScansperVolZEditFieldValueChanged(app, event)
            f_SLM_AO_update_total_modes(app);
        end

        % Button pushed function: PlotHologramButton
        function PlotHologramButtonPushed(app, event)
            [phase, n, m] = f_SLM_AO_gen_test_hologram(app);
            f_SLM_plot_phase_figure(phase, sprintf('Zernike Hologram %d, w=%d; Znm n=%d; m=%d',app.ModeindexEditField.Value,app.weightEditField.Value, n, m));
        end

        % Button pushed function: UploadHologramButton_2
        function UploadHologramButton_2Pushed(app, event)
            phase = f_SLM_AO_gen_test_hologram(app);
            app.SLM_Image_pointer.Value = f_SLM_convert_to_pointer(app, phase);
            f_SLM_BNS_update(app.SLM_ops, app.SLM_Image_pointer);
        end

        % Value changed function: ScanZernikeButton
        function ScanZernikeButtonValueChanged(app, event)
            f_SLM_AO_scan_zernike(app);
        end

        % Cell edit callback: ZernikeListTable
        function ZernikeListTableCellEdit(app, event)
            f_SLM_AO_update_total_modes(app);
        end

        % Value changed function: AOcorrectionfilesDropDown
        function AOcorrectionfilesDropDownValueChanged(app, event)
            app.SLM_ops.zernike_file_names = f_SLM_get_file_names([app.SLM_ops.GUI_dir '\' app.SLM_ops.calibration_dir], '*ernike*.mat', false);
            app.AOcorrectionfilesDropDown.Items  = app.SLM_ops.zernike_file_names;
            f_SLM_AO_generate_AO_image(app)
        end

        % Button pushed function: ComputeAOcorrectionButton
        function ComputeAOcorrectionButtonPushed(app, event)
            f_SLM_AO_generate_AO_image(app)
        end

        % Button pushed function: ViewAOphaseButton
        function ViewAOphaseButtonPushed(app, event)
            f_SLM_view_hologram_phase(app, app.SLM_AO_Image);
        end

        % Value changed function: RunLUTcalibrationButton
        function RunLUTcalibrationButtonValueChanged(app, event)
            f_SLM_LUT_run_calibration(app);
        end

        % Value changed function: BitDepthEditField
        function BitDepthEditFieldValueChanged(app, event)
            f_SLM_LUT_update_total_frames(app);
        end

        % Value changed function: NumRegionsEditField
        function NumRegionsEditFieldValueChanged(app, event)
            f_SLM_LUT_update_total_frames(app);
        end

        % Value changed function: LUTfilereactivateSLMDropDown
        function LUTfilereactivateSLMDropDownValueChanged(app, event)
            app.SLM_ops.current_lut = app.LUTfilereactivateSLMDropDown.Value;
        end

        % Value changed function: InsertrefimageinscansCheckBox
        function InsertrefimageinscansCheckBoxValueChanged(app, event)
            f_SLM_AO_update_total_modes(app);
            f_SLM_LUT_update_total_frames(app);
        end

        % Value changed function: SelectROIDropDown
        function SelectROIDropDownValueChanged(app, event)
            f_SLM_roi_update(app);
        end

        % Button pushed function: SaveROIButton
        function SaveROIButtonPushed(app, event)
            f_SLM_roi_save(app);
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            f_SLM_roi_add(app);
        end

        % Button pushed function: DeleteROIButton
        function DeleteROIButtonPushed(app, event)
            f_SLM_roi_delete(app);
        end

        % Button pushed function: SavepatternButton
        function SavepatternButtonPushed(app, event)
            f_SLM_pat_save(app);
        end

        % Value changed function: PatternlistDropDown
        function PatternlistDropDownValueChanged(app, event)
            f_SLM_pat_update(app);
        end

        % Button pushed function: DeletepatternButton
        function DeletepatternButtonPushed(app, event)
            f_SLM_pat_delete(app);
        end

        % Button pushed function: AddpatternButton
        function AddpatternButtonPushed(app, event)
            f_SLM_pat_add(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.SLMwidthEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMwidthEditFieldLabel.Text = 'SLM width';
            app.SLMwidthEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMwidthEditField.Editable = 'off';
            app.SLMwidthEditField.Value = 1920;
            app.SLMheightEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMheightEditFieldLabel.Text = 'SLM height';
            app.SLMheightEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMheightEditField.Editable = 'off';
            app.SLMheightEditField.Value = 1152;
            app.ObjectiveNAEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveNAEditFieldLabel.Text = 'Objective NA';
            app.ObjectiveNAEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveNAEditField.Value = 1.05;
            app.ObjectiveRIEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveRIEditFieldLabel.Text = 'Objective RI';
            app.ObjectiveRIEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveRIEditField.Value = 1.33;
            app.WavelengthnmEditFieldLabel = uilabel(app.ParametersTab);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.ParametersTab, 'numeric');
            app.WavelengthnmEditField.Value = 940;
            app.SLMpresetoffsetXLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetXLabel.Text = 'SLM preset offset X';
            app.SLMpresetoffsetXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditFieldLabel = uilabel(app.ParametersTab);
            app.SLMpresetoffsetYEditFieldLabel.Text = 'SLM preset offset Y';
            app.SLMpresetoffsetYEditField = uieditfield(app.ParametersTab, 'numeric');
            app.SLMpresetoffsetYEditField.Value = -9;
            app.ObjectiveMagXEditFieldLabel = uilabel(app.ParametersTab);
            app.ObjectiveMagXEditFieldLabel.Text = 'Objective Mag (X)';
            app.ObjectiveMagXEditField = uieditfield(app.ParametersTab, 'numeric');
            app.ObjectiveMagXEditField.Value = 25;
            app.BeamdiameterpixEditFieldLabel = uilabel(app.ParametersTab);
            app.BeamdiameterpixEditFieldLabel.Text = 'Beam diameter (pix)';
            app.BeamdiameterpixEditField = uieditfield(app.ParametersTab, 'numeric');
            app.BeamdiameterpixEditField.Value = 1152;
            app.NIDAQdeviceEditFieldLabel = uilabel(app.ParametersTab);
            app.NIDAQdeviceEditFieldLabel.Text = 'NI DAQ device';
            app.NIDAQdeviceEditField = uieditfield(app.ParametersTab, 'text');
            app.NIDAQdeviceEditField.Value = 'dev2';
            app.DAQcounterchannelEditFieldLabel = uilabel(app.ParametersTab);
            app.DAQcounterchannelEditFieldLabel.Text = 'DAQ counter channel';
            app.DAQcounterchannelEditField = uieditfield(app.ParametersTab, 'numeric');
            app.DAQcounterchannelEditField.Limits = [0 1];
            app.DAQcounterchannelEditField.RoundFractionalValues = 'on';
            app.PlotSLMupdateratesCheckBox = uicheckbox(app.ParametersTab);
            app.PlotSLMupdateratesCheckBox.Text = 'Plot SLM update rates';
            app.PlotSLMupdateratesCheckBox.Value = true;
            app.InsertrefimageinscansCheckBox = uicheckbox(app.ParametersTab);
            app.InsertrefimageinscansCheckBox.ValueChangedFcn = createCallbackFcn(app, @InsertrefimageinscansCheckBoxValueChanged, true);
            app.InsertrefimageinscansCheckBox.Text = 'Insert ref image in scans';
            app.InsertrefimageinscansCheckBox.Value = true;
            app.SaveopsButton = uibutton(app.ParametersTab, 'push');
            app.SaveopsButton.Text = 'Save ops';
            app.SLMROIeditorPanel = uipanel(app.ParametersTab);
            app.SLMROIeditorPanel.Title = 'SLM ROI editor';
            app.SelectROIDropDownLabel = uilabel(app.SLMROIeditorPanel);
            app.SelectROIDropDownLabel.Text = 'Select ROI';
            app.SelectROIDropDown = uidropdown(app.SLMROIeditorPanel);
            app.SelectROIDropDown.Items = {'Full SLM'};
            app.SelectROIDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectROIDropDownValueChanged, true);
            app.SelectROIDropDown.Value = 'Full SLM';
            app.ROInameEditFieldLabel = uilabel(app.SLMROIeditorPanel);
            app.ROInameEditFieldLabel.Text = 'ROI name';
            app.ROInameEditField = uieditfield(app.SLMROIeditorPanel, 'text');
            app.ROInameEditField.Value = 'Full SLM';
            app.AddROIButton = uibutton(app.SLMROIeditorPanel, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add ROI';
            app.DeleteROIButton = uibutton(app.SLMROIeditorPanel, 'push');
            app.DeleteROIButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteROIButtonPushed, true);
            app.DeleteROIButton.Text = 'Delete ROI';
            app.WavelengthnmEditField_2Label = uilabel(app.SLMROIeditorPanel);
            app.WavelengthnmEditField_2Label.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField_2 = uieditfield(app.SLMROIeditorPanel, 'numeric');
            app.WavelengthnmEditField_2.Value = 940;
            app.PixelfractionheightpixpixEditFieldLabel = uilabel(app.SLMROIeditorPanel);
            app.PixelfractionheightpixpixEditFieldLabel.Text = 'Pixel fraction height (pix:pix)';
            app.PixelfractionheightpixpixEditField = uieditfield(app.SLMROIeditorPanel, 'text');
            app.PixelfractionheightpixpixEditField.Value = '1:1152';
            app.PixelfractionwidthpixpixEditFieldLabel = uilabel(app.SLMROIeditorPanel);
            app.PixelfractionwidthpixpixEditFieldLabel.Text = 'Pixel fraction width (pix:pix)';
            app.PixelfractionwidthpixpixEditField = uieditfield(app.SLMROIeditorPanel, 'text');
            app.PixelfractionwidthpixpixEditField.Value = '1:1920';
            app.LUTfilereactivateSLMDropDownLabel = uilabel(app.SLMROIeditorPanel);
            app.LUTfilereactivateSLMDropDownLabel.Text = {'LUT file'; '(reactivate SLM)'};
            app.LUTfilereactivateSLMDropDown = uidropdown(app.SLMROIeditorPanel);
            app.LUTfilereactivateSLMDropDown.ValueChangedFcn = createCallbackFcn(app, @LUTfilereactivateSLMDropDownValueChanged, true);
            app.SaveROIButton = uibutton(app.SLMROIeditorPanel, 'push');
            app.SaveROIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROIButtonPushed, true);
            app.SaveROIButton.Text = 'Save ROI';
            app.XYZpatternTab = uitab(app.TabGroup);
            app.XYZpatternTab.Title = 'XYZ pattern';
            app.ListofpointsLabel = uilabel(app.XYZpatternTab);
            app.ListofpointsLabel.Text = 'List of points';
            app.CreateimagingphasePanel = uipanel(app.XYZpatternTab);
            app.CreateimagingphasePanel.Title = 'Create imaging phase';
            app.UploadHologramButton = uibutton(app.CreateimagingphasePanel, 'push');
            app.UploadHologramButton.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonPushedMultiplane, true);
            app.UploadHologramButton.Text = 'Upload Hologram';
            app.ZOffsetumEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.ZOffsetumEditFieldLabel.Text = 'Z Offset (um)';
            app.ZOffsetumEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ManualNAcorrectionCheckBox = uicheckbox(app.CreateimagingphasePanel);
            app.ManualNAcorrectionCheckBox.Text = 'Manual NA correction';
            app.ManualNAEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.ManualNAEditFieldLabel.Text = 'Manual NA';
            app.ManualNAEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ManualNAEditField.Limits = [0 2];
            app.ManualNAEditField.ValueDisplayFormat = '%.2f';
            app.ManualNAEditField.Value = 0.95;
            app.DefocusLabel = uilabel(app.CreateimagingphasePanel);
            app.DefocusLabel.Text = 'Defocus';
            app.XYdisplacementLabel = uilabel(app.CreateimagingphasePanel);
            app.XYdisplacementLabel.Text = 'X Y displacement';
            app.XdisplacementEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.XdisplacementEditFieldLabel.Text = 'X displacement';
            app.XdisplacementEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.YdisplacementEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.YdisplacementEditFieldLabel.Text = 'Y displacement';
            app.YdisplacementEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.ViewHologramButtonMultiplane = uibutton(app.CreateimagingphasePanel, 'push');
            app.ViewHologramButtonMultiplane.ButtonPushedFcn = createCallbackFcn(app, @ViewHologramButtonPushedMultiplane, true);
            app.ViewHologramButtonMultiplane.Text = 'View Hologram';
            app.WeightEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.WeightEditField.Value = 1;
            app.PlanenumberEditFieldLabel = uilabel(app.CreateimagingphasePanel);
            app.PlanenumberEditFieldLabel.Text = 'Plane number';
            app.PlanenumberEditField = uieditfield(app.CreateimagingphasePanel, 'numeric');
            app.PlanenumberEditField.Limits = [1 Inf];
            app.PlanenumberEditField.RoundFractionalValues = 'on';
            app.PlanenumberEditField.Value = 1;
            app.UploadselectedButton = uibutton(app.XYZpatternTab, 'push');
            app.UploadselectedButton.ButtonPushedFcn = createCallbackFcn(app, @UploadselectedButtonPushed, true);
            app.UploadselectedButton.Text = 'Upload selected';
            app.UIImagePhaseTable = uitable(app.XYZpatternTab);
            app.UIImagePhaseTable.ColumnName = {'Plane #'; 'Z (um)'; 'X'; 'Y'; 'NA'; 'Weight'};
            app.UIImagePhaseTable.RowName = {};
            app.UIImagePhaseTable.ColumnSortable = true;
            app.UIImagePhaseTable.ColumnEditable = true;
            app.UIImagePhaseTable.CellSelectionCallback = createCallbackFcn(app, @UIImagePhaseTableCellSelection, true);
            app.RemoveselectedButton = uibutton(app.XYZpatternTab, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.AddtohologramButton = uibutton(app.XYZpatternTab, 'push');
            app.AddtohologramButton.ButtonPushedFcn = createCallbackFcn(app, @AddtohologramButtonPushed, true);
            app.AddtohologramButton.Text = 'Add to hologram ->';
            app.ViewpredictedPSFButton = uibutton(app.XYZpatternTab, 'push');
            app.ViewpredictedPSFButton.Text = 'View predicted PSF';
            app.ViewselectedphaseButton = uibutton(app.XYZpatternTab, 'push');
            app.ViewselectedphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedphaseButtonPushed, true);
            app.ViewselectedphaseButton.Text = 'View selected phase';
            app.PlaneSpinnerLabel = uilabel(app.XYZpatternTab);
            app.PlaneSpinnerLabel.Text = 'Plane';
            app.PlaneSpinner = uispinner(app.XYZpatternTab);
            app.PlaneSpinner.RoundFractionalValues = 'on';
            app.PlaneSpinner.Value = 1;
            app.ViewplanephaseButton = uibutton(app.XYZpatternTab, 'push');
            app.ViewplanephaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewplanephaseButtonPushed, true);
            app.ViewplanephaseButton.Text = 'View plane phase';
            app.UploadPlaneButton = uibutton(app.XYZpatternTab, 'push');
            app.UploadPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPlaneButtonPushed, true);
            app.UploadPlaneButton.Text = 'Upload Plane';
            app.ViewselectedfftButton = uibutton(app.XYZpatternTab, 'push');
            app.ViewselectedfftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewselectedfftButtonPushed, true);
            app.ViewselectedfftButton.Text = 'View selected fft';
            app.ViewplanefftButton = uibutton(app.XYZpatternTab, 'push');
            app.ViewplanefftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewplanefftButtonPushed, true);
            app.ViewplanefftButton.Text = 'View plane fft';
            app.fftdefocusumEditFieldLabel = uilabel(app.XYZpatternTab);
            app.fftdefocusumEditFieldLabel.Text = 'fft defocus (um)';
            app.fftdefocusumEditField = uieditfield(app.XYZpatternTab, 'numeric');
            app.fftampsquaredCheckBox = uicheckbox(app.XYZpatternTab);
            app.fftampsquaredCheckBox.Text = 'fft amp squared';
            app.fftampsquaredCheckBox.Value = true;
            app.fftzoomed20xCheckBox = uicheckbox(app.XYZpatternTab);
            app.fftzoomed20xCheckBox.Text = 'fft zoomed 20x';
            app.fftzoomed20xCheckBox.Value = true;
            app.PatternlistDropDownLabel = uilabel(app.XYZpatternTab);
            app.PatternlistDropDownLabel.Text = 'Pattern list';
            app.PatternlistDropDown = uidropdown(app.XYZpatternTab);
            app.PatternlistDropDown.Items = {'Multiplane'};
            app.PatternlistDropDown.ValueChangedFcn = createCallbackFcn(app, @PatternlistDropDownValueChanged, true);
            app.PatternlistDropDown.Value = 'Multiplane';
            app.PatternROIDropDownLabel = uilabel(app.XYZpatternTab);
            app.PatternROIDropDownLabel.Text = 'Pattern ROI';
            app.PatternROIDropDown = uidropdown(app.XYZpatternTab);
            app.PatternnameEditFieldLabel = uilabel(app.XYZpatternTab);
            app.PatternnameEditFieldLabel.Text = 'Pattern name';
            app.PatternnameEditField = uieditfield(app.XYZpatternTab, 'text');
            app.SavepatternButton = uibutton(app.XYZpatternTab, 'push');
            app.SavepatternButton.ButtonPushedFcn = createCallbackFcn(app, @SavepatternButtonPushed, true);
            app.SavepatternButton.Text = 'Save pattern';
            app.AddpatternButton = uibutton(app.XYZpatternTab, 'push');
            app.AddpatternButton.ButtonPushedFcn = createCallbackFcn(app, @AddpatternButtonPushed, true);
            app.AddpatternButton.Text = 'Add pattern';
            app.DeletepatternButton = uibutton(app.XYZpatternTab, 'push');
            app.DeletepatternButton.ButtonPushedFcn = createCallbackFcn(app, @DeletepatternButtonPushed, true);
            app.DeletepatternButton.Text = 'Delete pattern';
            app.UIAxes = uiaxes(app.XYZpatternTab);
            title(app.UIAxes, 'Title')
            zlabel(app.UIAxes, 'Z')
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.FrameswitchingmethodButtonGroup = uibuttongroup(app.ScanTab);
            app.FrameswitchingmethodButtonGroup.Title = 'Frame switching method';
            app.EndofframetriggeringButton = uitogglebutton(app.FrameswitchingmethodButtonGroup);
            app.EndofframetriggeringButton.Text = 'End of frame triggering';
            app.EndofframetriggeringButton.Value = true;
            app.TimedelayButton = uitogglebutton(app.FrameswitchingmethodButtonGroup);
            app.TimedelayButton.Text = 'Time delay';
            app.ImagePanel = uipanel(app.ScanTab);
            app.ImagePanel.Title = 'Image';
            app.InitializeimagingButton = uibutton(app.ImagePanel, 'state');
            app.InitializeimagingButton.ValueChangedFcn = createCallbackFcn(app, @InitializeimagingButtonValueChanged, true);
            app.InitializeimagingButton.Text = 'Initialize imaging';
            app.ImagingReadyLamp = uilamp(app.ImagePanel);
            app.NumVolumesEditFieldLabel = uilabel(app.ImagePanel);
            app.NumVolumesEditFieldLabel.Text = 'Num Volumes';
            app.NumVolumesEditField = uieditfield(app.ImagePanel, 'numeric');
            app.NumVolumesEditField.Limits = [1 Inf];
            app.NumVolumesEditField.Value = 1;
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.LUTcalibrationdataPanel = uipanel(app.CalibrationTab);
            app.LUTcalibrationdataPanel.Title = 'LUT calibration data';
            app.BitDepthEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BitDepthEditFieldLabel.Text = 'Bit Depth';
            app.BitDepthEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BitDepthEditField.Limits = [1 256];
            app.BitDepthEditField.RoundFractionalValues = 'on';
            app.BitDepthEditField.ValueChangedFcn = createCallbackFcn(app, @BitDepthEditFieldValueChanged, true);
            app.BitDepthEditField.Value = 256;
            app.NumRegionsEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.NumRegionsEditFieldLabel.Text = 'Num Regions';
            app.NumRegionsEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.NumRegionsEditField.Limits = [1 Inf];
            app.NumRegionsEditField.RoundFractionalValues = 'on';
            app.NumRegionsEditField.ValueChangedFcn = createCallbackFcn(app, @NumRegionsEditFieldValueChanged, true);
            app.NumRegionsEditField.Value = 1;
            app.PixelsPerStripeEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.PixelsPerStripeEditFieldLabel.Text = 'Pixels Per Stripe';
            app.PixelsPerStripeEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.PixelsPerStripeEditField.Limits = [1 Inf];
            app.PixelsPerStripeEditField.Value = 8;
            app.AnalysissavefilenameEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.AnalysissavefilenameEditFieldLabel.Text = 'Analysis save file name';
            app.AnalysissavefilenameEditField = uieditfield(app.LUTcalibrationdataPanel, 'text');
            app.AnalysissavefilenameEditField.Value = '940_slm5221_maitai2';
            app.RunLUTcalibrationButton = uibutton(app.LUTcalibrationdataPanel, 'state');
            app.RunLUTcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @RunLUTcalibrationButtonValueChanged, true);
            app.RunLUTcalibrationButton.Text = 'Run LUT calibration';
            app.AnalyzeLUTdataButton = uibutton(app.LUTcalibrationdataPanel, 'push');
            app.AnalyzeLUTdataButton.Text = 'Analyze LUT data';
            app.RunLUTcalibReadyLamp = uilamp(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.BaselinepixelvalueEditFieldLabel.Text = 'Baseline pixel value';
            app.BaselinepixelvalueEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.BaselinepixelvalueEditField.Limits = [0 255];
            app.BaselinepixelvalueEditField.RoundFractionalValues = 'on';
            app.TotalframesEditFieldLabel = uilabel(app.LUTcalibrationdataPanel);
            app.TotalframesEditFieldLabel.Text = 'Total frames';
            app.TotalframesEditField = uieditfield(app.LUTcalibrationdataPanel, 'numeric');
            app.TotalframesEditField.Editable = 'off';
            app.AxialcalibrationZPanel = uipanel(app.CalibrationTab);
            app.AxialcalibrationZPanel.Tooltip = {'Best to use fluorescent beeds'; ' a 3D specimen. Use multiplane feature to offset in Z by a distance (+-50'; '100'; '150)'; ' and use the microscope stage to measure the actual translation.'};
            app.AxialcalibrationZPanel.Title = 'Axial calibration (Z)';
            app.AxialcalibrationfileEditFieldLabel = uilabel(app.AxialcalibrationZPanel);
            app.AxialcalibrationfileEditFieldLabel.Text = 'Axial calibration file';
            app.AxialcalibrationfileEditField = uieditfield(app.AxialcalibrationZPanel, 'text');
            app.AxialcalibrationfileEditField.Value = 'axial_calibration_3_11_20.mat';
            app.PlotaxialcalibrationdataButton = uibutton(app.AxialcalibrationZPanel, 'push');
            app.PlotaxialcalibrationdataButton.Text = 'Plot axial calibration data';
            app.LateralcalibrationxyaffinetransformPanel = uipanel(app.CalibrationTab);
            app.LateralcalibrationxyaffinetransformPanel.Title = 'Lateral calibration (x,y) affine transform';
            app.AffinetransformatiomatrixfileEditFieldLabel = uilabel(app.LateralcalibrationxyaffinetransformPanel);
            app.AffinetransformatiomatrixfileEditFieldLabel.Text = 'Affine transformatio matrix file';
            app.AffinetransformatiomatrixfileEditField = uieditfield(app.LateralcalibrationxyaffinetransformPanel, 'text');
            app.AffinetransformatiomatrixfileEditField.Value = 'lateral_affine_transform_mat_3_3_20.mat';
            app.LateralpixelumscalingfileEditFieldLabel = uilabel(app.LateralcalibrationxyaffinetransformPanel);
            app.LateralpixelumscalingfileEditFieldLabel.Text = 'Lateral pixel/um scaling file';
            app.LateralpixelumscalingfileEditField = uieditfield(app.LateralcalibrationxyaffinetransformPanel, 'text');
            app.LateralpixelumscalingfileEditField.Value = 'lateral_calibration_pixel_um_3_3_20.mat';
            app.GenerateHologramTab = uitab(app.TabGroup);
            app.GenerateHologramTab.Title = 'Generate Hologram';
            app.TabGroup2 = uitabgroup(app.GenerateHologramTab);
            app.BlankTab = uitab(app.TabGroup2);
            app.BlankTab.Title = 'Blank';
            app.GenerateBlankButton = uibutton(app.BlankTab, 'push');
            app.GenerateBlankButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlankLensButtonPushed, true);
            app.GenerateBlankButton.Text = 'Generate Blank';
            app.PixelValueLabel = uilabel(app.BlankTab);
            app.PixelValueLabel.Text = 'Pixel Value';
            app.BlankPixelValueEditField = uieditfield(app.BlankTab, 'numeric');
            app.FresnelLensTab = uitab(app.TabGroup2);
            app.FresnelLensTab.Title = 'Fresnel Lens';
            app.GenerateFresnelLensButton = uibutton(app.FresnelLensTab, 'push');
            app.GenerateFresnelLensButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFresnelLensButtonPushed, true);
            app.GenerateFresnelLensButton.Text = 'Generate Fresnel';
            app.CenterXEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterXEditFieldLabel.Text = 'CenterX';
            app.FresCenterXEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterXEditField.Value = 960;
            app.CenterYEditFieldLabel = uilabel(app.FresnelLensTab);
            app.CenterYEditFieldLabel.Text = 'CenterY';
            app.FresCenterYEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresCenterYEditField.Value = 576;
            app.RadiusEditFieldLabel = uilabel(app.FresnelLensTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.FresRadiusEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresRadiusEditField.Value = 576;
            app.PowerEditFieldLabel = uilabel(app.FresnelLensTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.FresPowerEditField = uieditfield(app.FresnelLensTab, 'numeric');
            app.FresPowerEditField.Value = 1;
            app.FresCylindricalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresCylindricalCheckBox.Text = 'Cylindrical';
            app.FresCylindricalCheckBox.Value = true;
            app.FresHorizontalCheckBox = uicheckbox(app.FresnelLensTab);
            app.FresHorizontalCheckBox.Text = 'Horizontal';
            app.ZernikeTab = uitab(app.TabGroup2);
            app.ZernikeTab.Title = 'Zernike';
            app.GenerateZernikeButton = uibutton(app.ZernikeTab, 'push');
            app.GenerateZernikeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateZernikeButtonPushed, true);
            app.GenerateZernikeButton.Text = 'Generate Zernike';
            app.CenterXEditField_2Label = uilabel(app.ZernikeTab);
            app.CenterXEditField_2Label.Text = 'CenterX';
            app.CenterXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.CenterYEditField_5Label = uilabel(app.ZernikeTab);
            app.CenterYEditField_5Label.Text = 'CenterY';
            app.CenterYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.RadiusEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.RadiusEditFieldLabel_2.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PistonEditFieldLabel = uilabel(app.ZernikeTab);
            app.PistonEditFieldLabel.Text = 'Piston';
            app.PistonEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltXEditFieldLabel.Text = 'TiltX';
            app.TiltXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TiltYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TiltYEditFieldLabel.Text = 'TiltY';
            app.TiltYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PowerEditFieldLabel_2 = uilabel(app.ZernikeTab);
            app.PowerEditFieldLabel_2.Text = 'Power';
            app.PowerEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigXEditFieldLabel.Text = 'AstigX';
            app.AstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.AstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.AstigYEditFieldLabel.Text = 'AstigY';
            app.AstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaXEditFieldLabel.Text = 'ComaX';
            app.ComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.ComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.ComaYEditFieldLabel.Text = 'ComaY';
            app.ComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.PrimarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.PrimarySphericalEditFieldLabel.Text = 'PrimarySpherical';
            app.PrimarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilXEditFieldLabel.Text = 'TrefoilX';
            app.TrefoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TrefoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TrefoilYEditFieldLabel.Text = 'TrefoilY';
            app.TrefoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigXEditFieldLabel.Text = 'SecondaryAstigX';
            app.SecondaryAstigXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryAstigYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryAstigYEditFieldLabel.Text = 'SecondaryAstigY';
            app.SecondaryAstigYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaXEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaXEditFieldLabel.Text = 'SecondaryComaX';
            app.SecondaryComaXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondaryComaYEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondaryComaYEditFieldLabel.Text = 'SecondaryComaY';
            app.SecondaryComaYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.SecondarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.SecondarySphericalEditFieldLabel.Text = 'SecondarySpherical';
            app.SecondarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilXEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilXEditFieldLabel.Text = 'TetrafoilX';
            app.TetrafoilXEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TetrafoilYEditFieldLabel = uilabel(app.ZernikeTab);
            app.TetrafoilYEditFieldLabel.Text = 'TetrafoilY';
            app.TetrafoilYEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.TertiarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.TertiarySphericalEditFieldLabel.Text = 'TertiarySpherical';
            app.TertiarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.QuaternarySphericalEditFieldLabel = uilabel(app.ZernikeTab);
            app.QuaternarySphericalEditFieldLabel.Text = 'QuaternarySpherical';
            app.QuaternarySphericalEditField = uieditfield(app.ZernikeTab, 'numeric');
            app.BlazedGratingTab = uitab(app.TabGroup2);
            app.BlazedGratingTab.Title = 'Blazed Grating';
            app.GenerateBlazedGratingButton = uibutton(app.BlazedGratingTab, 'push');
            app.GenerateBlazedGratingButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBlazedGratingButtonPushed, true);
            app.GenerateBlazedGratingButton.Text = 'Generate Blazed';
            app.PeriodEditFieldLabel = uilabel(app.BlazedGratingTab);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.BlazPeriodEditField = uieditfield(app.BlazedGratingTab, 'numeric');
            app.BlazPeriodEditField.Value = 128;
            app.BlazIncreasingCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazIncreasingCheckBox.Text = 'Increasing';
            app.BlazIncreasingCheckBox.Value = true;
            app.BlazHorizontalCheckBox = uicheckbox(app.BlazedGratingTab);
            app.BlazHorizontalCheckBox.Text = 'Horizontal';
            app.StripesTab = uitab(app.TabGroup2);
            app.StripesTab.Title = 'Stripes';
            app.PixelsPerStripeLabel = uilabel(app.StripesTab);
            app.PixelsPerStripeLabel.Text = 'Pixels Per Stripe';
            app.StripePixelPerStripeEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripePixelPerStripeEditField.Value = 8;
            app.GenerateStripesButton = uibutton(app.StripesTab, 'push');
            app.GenerateStripesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateStripesButtonPushed, true);
            app.GenerateStripesButton.Text = 'Generate Stripes';
            app.PixelValueEditFieldLabel = uilabel(app.StripesTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.StripePixelValueEditField = uieditfield(app.StripesTab, 'numeric');
            app.GrayEditFieldLabel = uilabel(app.StripesTab);
            app.GrayEditFieldLabel.Text = 'Gray';
            app.StripeGrayEditField = uieditfield(app.StripesTab, 'numeric');
            app.StripeGrayEditField.Value = 255;
            app.DefocusTab = uitab(app.TabGroup2);
            app.DefocusTab.Title = 'Defocus';
            app.UploadHologramButtonGenerate = uibutton(app.GenerateHologramTab, 'push');
            app.UploadHologramButtonGenerate.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButtonGeneratePushedGenerate, true);
            app.UploadHologramButtonGenerate.Text = 'Upload Hologram';
            app.SelectROIDropDown_2Label = uilabel(app.GenerateHologramTab);
            app.SelectROIDropDown_2Label.Text = 'Select ROI';
            app.SelectROIDropDown_2 = uidropdown(app.GenerateHologramTab);
            app.UIAxesGenerateHologram = uiaxes(app.GenerateHologramTab);
            title(app.UIAxesGenerateHologram, 'Hologram Phase')
            xlabel(app.UIAxesGenerateHologram, 'X pixels')
            ylabel(app.UIAxesGenerateHologram, 'Y pixels')
            app.AdaptiveOpticsmodalTab = uitab(app.TabGroup);
            app.AdaptiveOpticsmodalTab.Title = 'Adaptive Optics modal';
            app.ZernikeListTable = uitable(app.AdaptiveOpticsmodalTab);
            app.ZernikeListTable.ColumnName = {'idx'; 'Zn'; 'Zm'; 'W - lim'; 'W step'; 'W + lim'; 'Include'};
            app.ZernikeListTable.RowName = {};
            app.ZernikeListTable.ColumnEditable = [false false false true true true true];
            app.ZernikeListTable.CellEditCallback = createCallbackFcn(app, @ZernikeListTableCellEdit, true);
            app.ZernikeModestoscanadjustweightsifneededLabel = uilabel(app.AdaptiveOpticsmodalTab);
            app.ZernikeModestoscanadjustweightsifneededLabel.Text = 'Zernike Modes to scan (adjust weights if needed)';
            app.TestHologramandweightsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.TestHologramandweightsPanel.Title = 'Test Hologram and weights';
            app.PlotHologramButton = uibutton(app.TestHologramandweightsPanel, 'push');
            app.PlotHologramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHologramButtonPushed, true);
            app.PlotHologramButton.Text = 'Plot Hologram';
            app.UploadHologramButton_2 = uibutton(app.TestHologramandweightsPanel, 'push');
            app.UploadHologramButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadHologramButton_2Pushed, true);
            app.UploadHologramButton_2.Text = 'Upload Hologram';
            app.ModeindexEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.ModeindexEditFieldLabel.Text = 'Mode index';
            app.ModeindexEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.ModeindexEditField.Limits = [1 Inf];
            app.ModeindexEditField.RoundFractionalValues = 'on';
            app.ModeindexEditField.Value = 1;
            app.weightEditFieldLabel = uilabel(app.TestHologramandweightsPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.TestHologramandweightsPanel, 'numeric');
            app.weightEditField.Value = 1;
            app.AOscanZernikepolynomialsPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AOscanZernikepolynomialsPanel.Title = 'AO scan Zernike polynomials';
            app.ZernikeReadyLamp = uilamp(app.AOscanZernikepolynomialsPanel);
            app.ScanZernikeButton = uibutton(app.AOscanZernikepolynomialsPanel, 'state');
            app.ScanZernikeButton.ValueChangedFcn = createCallbackFcn(app, @ScanZernikeButtonValueChanged, true);
            app.ScanZernikeButton.Text = 'Scan Zernike';
            app.ShufflemodesCheckBox = uicheckbox(app.AOscanZernikepolynomialsPanel);
            app.ShufflemodesCheckBox.Text = 'Shuffle modes';
            app.ShufflemodesCheckBox.Value = true;
            app.SavefiletagEditFieldLabel = uilabel(app.AOscanZernikepolynomialsPanel);
            app.SavefiletagEditFieldLabel.Text = 'Save file tag';
            app.SavefiletagEditField = uieditfield(app.AOscanZernikepolynomialsPanel, 'text');
            app.SavefiletagEditField.Value = 'zernike_scan_data';
            app.ScanspermodeEditFieldLabel = uilabel(app.AOscanZernikepolynomialsPanel);
            app.ScanspermodeEditFieldLabel.Text = 'Scans per mode';
            app.ScanspermodeEditField = uieditfield(app.AOscanZernikepolynomialsPanel, 'numeric');
            app.ScanspermodeEditField.Limits = [1 Inf];
            app.ScanspermodeEditField.ValueChangedFcn = createCallbackFcn(app, @ScanspermodeEditFieldValueChanged, true);
            app.ScanspermodeEditField.Value = 1;
            app.ScansperVolZEditFieldLabel = uilabel(app.AOscanZernikepolynomialsPanel);
            app.ScansperVolZEditFieldLabel.Text = 'Scans per Vol (Z)';
            app.ScansperVolZEditField = uieditfield(app.AOscanZernikepolynomialsPanel, 'numeric');
            app.ScansperVolZEditField.Limits = [1 Inf];
            app.ScansperVolZEditField.RoundFractionalValues = 'on';
            app.ScansperVolZEditField.ValueChangedFcn = createCallbackFcn(app, @ScansperVolZEditFieldValueChanged, true);
            app.ScansperVolZEditField.Value = 1;
            app.AnalyzeoutputimagesPanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.AnalyzeoutputimagesPanel.Title = 'Analyze output images';
            app.LoaddataButton = uibutton(app.AnalyzeoutputimagesPanel, 'push');
            app.LoaddataButton.Text = 'Load data';
            app.AnalyzeButton = uibutton(app.AnalyzeoutputimagesPanel, 'push');
            app.AnalyzeButton.Text = 'Analyze';
            app.TotalVolumesmodesEditFieldLabel = uilabel(app.AdaptiveOpticsmodalTab);
            app.TotalVolumesmodesEditFieldLabel.Text = 'Total Volumes (modes)';
            app.TotalVolumesmodesEditField = uieditfield(app.AdaptiveOpticsmodalTab, 'numeric');
            app.TotalVolumesmodesEditField.Limits = [0 Inf];
            app.TotalVolumesmodesEditField.RoundFractionalValues = 'on';
            app.TotalVolumesmodesEditField.Editable = 'off';
            app.FillzerniketablePanel = uipanel(app.AdaptiveOpticsmodalTab);
            app.FillzerniketablePanel.Title = 'Fill zernike table';
            app.FillmodestableButton = uibutton(app.FillzerniketablePanel, 'push');
            app.FillmodestableButton.ButtonPushedFcn = createCallbackFcn(app, @FillmodestableButtonPushed, true);
            app.FillmodestableButton.Text = 'Fill modes table';
            app.MaxmodesEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.MaxmodesEditFieldLabel.Text = 'Max modes';
            app.MaxmodesEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.MaxmodesEditField.Value = 4;
            app.WeightlimitEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WeightlimitEditFieldLabel.Text = 'Weight limit';
            app.WeightlimitEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WeightlimitEditField.Value = 3;
            app.WeightstepEditFieldLabel = uilabel(app.FillzerniketablePanel);
            app.WeightstepEditFieldLabel.Text = 'Weight step';
            app.WeightstepEditField = uieditfield(app.FillzerniketablePanel, 'numeric');
            app.WeightstepEditField.Value = 1;
            app.ComputeAOcorrectionButton = uibutton(app.AdaptiveOpticsmodalTab, 'push');
            app.ComputeAOcorrectionButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeAOcorrectionButtonPushed, true);
            app.ComputeAOcorrectionButton.Text = 'Compute AO correction';
            app.ViewAOphaseButton = uibutton(app.AdaptiveOpticsmodalTab, 'push');
            app.ViewAOphaseButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAOphaseButtonPushed, true);
            app.ViewAOphaseButton.Text = 'View AO phase';
            app.AOcorrectionfilesDropDownLabel = uilabel(app.AdaptiveOpticsmodalTab);
            app.AOcorrectionfilesDropDownLabel.Text = 'AO correction files';
            app.AOcorrectionfilesDropDown = uidropdown(app.AdaptiveOpticsmodalTab);
            app.AOcorrectionfilesDropDown.ValueChangedFcn = createCallbackFcn(app, @AOcorrectionfilesDropDownValueChanged, true);
            app.TotalframesEditField_2Label = uilabel(app.AdaptiveOpticsmodalTab);
            app.TotalframesEditField_2Label.Text = 'Total frames';
            app.TotalframesEditField_AO = uieditfield(app.AdaptiveOpticsmodalTab, 'numeric');
            app.TotalframesEditField_AO.Limits = [0 Inf];
            app.TotalframesEditField_AO.RoundFractionalValues = 'on';
            app.TotalframesEditField_AO.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ActivateSLMLamp = uilamp(app.Panel);
            app.ActivateSLMButton = uibutton(app.Panel, 'state');
            app.ActivateSLMButton.ValueChangedFcn = createCallbackFcn(app, @ActivateSLMButtonValueChanged, true);
            app.ActivateSLMButton.Text = 'Activate SLM';
            app.InitializeDAQButton = uibutton(app.Panel, 'push');
            app.InitializeDAQButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDAQButtonPushed, true);
            app.InitializeDAQButton.Text = 'Initialize DAQ';
            app.InitializeDAQLamp = uilamp(app.Panel);
            app.BlankSLMButton = uibutton(app.Panel, 'push');
            app.BlankSLMButton.ButtonPushedFcn = createCallbackFcn(app, @BlankSLMButtonPushed, true);
            app.BlankSLMButton.Text = 'Blank SLM';
            app.ApplyAOcorrectionButton = uibutton(app.Panel, 'state');
            app.ApplyAOcorrectionButton.ValueChangedFcn = createCallbackFcn(app, @ApplyAOcorrectionButtonValueChanged, true);
            app.ApplyAOcorrectionButton.Text = {'Apply AO'; 'correction'};
            app.InitializeAOLamp = uilamp(app.Panel);
            app.refimSLMButton = uibutton(app.Panel, 'push');
            app.refimSLMButton.ButtonPushedFcn = createCallbackFcn(app, @refimSLMButtonPushed, true);
            app.refimSLMButton.Text = 'ref im SLM';
            app.XoffsetButton = uibutton(app.Panel, 'push');
            app.XoffsetButton.ButtonPushedFcn = createCallbackFcn(app, @XoffsetButtonPushed, true);
            app.XoffsetButton.Text = 'X offset';
            app.AOcorrectionavailableLampLabel = uilabel(app.Panel);
            app.AOcorrectionavailableLampLabel.Text = {'AO correction'; 'available'};
            app.AOcorrectionavailableLamp = uilamp(app.Panel);
            app.UITablecurrentcoord = uitable(app.Panel);
            app.UITablecurrentcoord.ColumnName = {'x'; 'y'; 'z'};
            app.UITablecurrentcoord.RowName = {};
            app.CurrentcoordLabel = uilabel(app.Panel);
            app.CurrentcoordLabel.Text = 'Current coord';
            app.ApplyXYZcalibrationButton = uibutton(app.Panel, 'state');
            app.ApplyXYZcalibrationButton.ValueChangedFcn = createCallbackFcn(app, @ApplyXYZcalibrationButtonValueChanged, true);
            app.ApplyXYZcalibrationButton.Text = {'Apply XYZ'; 'calibration'};
            app.XYZcalibrationLamp = uilamp(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SLM_control_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end